classdef COV_2o < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        forCoVmapeachsideoftheprojectionimageLabel  matlab.ui.control.Label
        showCropCoV                     matlab.ui.control.NumericEditField
        CroppingSizeinpixelEditFieldLabel_2  matlab.ui.control.Label
        RepeatabilityReproducibilityButton  matlab.ui.control.Button
        SelectTimepointsLabel           matlab.ui.control.Label
        customVTitle                    matlab.ui.control.Label
        customETitle                    matlab.ui.control.Label
        zeissETitle                     matlab.ui.control.Label
        CustomV                         matlab.ui.control.Image
        CustomE                         matlab.ui.control.Image
        ZeissE                          matlab.ui.control.Image
        std_CustomVLabel                matlab.ui.control.Label
        mu_CustomVLabel                 matlab.ui.control.Label
        std_CustomELabel                matlab.ui.control.Label
        mu_CustomELabel                 matlab.ui.control.Label
        std_ZeissELabel                 matlab.ui.control.Label
        mu_ZeissELabel                  matlab.ui.control.Label
        VisualizationButton             matlab.ui.control.Button
        std_CustomV                     matlab.ui.control.NumericEditField
        mu_CustomV                      matlab.ui.control.NumericEditField
        std_CustomE                     matlab.ui.control.NumericEditField
        mu_CustomE                      matlab.ui.control.NumericEditField
        std_ZeissE                      matlab.ui.control.NumericEditField
        mu_ZeissE                       matlab.ui.control.NumericEditField
        SelectTimepointsDropDown        matlab.ui.control.DropDown
        plotVisualization               matlab.ui.control.Button
        fornoiseestimationdeterminedviatrialanderrorLabel  matlab.ui.control.Label
        showOffset                      matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        GenerateProjectionImagesButton  matlab.ui.control.Button
        CancelProcessing                matlab.ui.control.Button
        LayersegmentationFn             matlab.ui.control.EditField
        SegmentationLabel               matlab.ui.control.Label
        eachsideoftheprojectionimageLabel  matlab.ui.control.Label
        cropSize                        matlab.ui.control.NumericEditField
        CroppingSizeinpixelEditFieldLabel  matlab.ui.control.Label
        ClearRuntimeLog                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        RawOCTvolumeLabel               matlab.ui.control.Label
        OCTAprojectionimageLabel        matlab.ui.control.Label
        SelectedRegistrationTemplateLabel  matlab.ui.control.Label
        IftherearemultipletimepointsseparatethemusingcommaLabel  matlab.ui.control.Label
        template_cube_fn                matlab.ui.control.EditField
        template_proj_fn                matlab.ui.control.EditField
        RuntimeLogTextArea              matlab.ui.control.TextArea
        RuntimeLogTextAreaLabel         matlab.ui.control.Label
        upperRegistration               matlab.ui.control.NumericEditField
        EnterthelowerboundaryofthedepthforregistrationLabel  matlab.ui.control.Label
        AscanEditField                  matlab.ui.control.NumericEditField
        AscanEditFieldLabel             matlab.ui.control.Label
        FrameEditField                  matlab.ui.control.NumericEditField
        FrameEditFieldLabel             matlab.ui.control.Label
        PointEditField                  matlab.ui.control.NumericEditField
        PointEditFieldLabel             matlab.ui.control.Label
        readtimepoints                  matlab.ui.control.EditField
        TimepointsEditFieldLabel        matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        DiseaseDropDown                 matlab.ui.control.DropDown
        DiseaseDropDownLabel            matlab.ui.control.Label
        LowerCovCompute                 matlab.ui.control.NumericEditField
        EntertheupperboundaryofthedepthforCoVcomputationLabel_2  matlab.ui.control.Label
        UpperCovCompute                 matlab.ui.control.NumericEditField
        EntertheupperboundaryofthedepthforCoVcomputationLabel  matlab.ui.control.Label
        lowerRegistration               matlab.ui.control.NumericEditField
        EntertheupperboundaryofthedepthforregistrationLabel  matlab.ui.control.Label
        ComputeCoVLabel                 matlab.ui.control.Label
        DefineInitialParametersLabel    matlab.ui.control.Label
        DMultivolumeRegistrationLabel   matlab.ui.control.Label
        setParameters                   matlab.ui.control.Button
        funcpathDirect                  matlab.ui.control.EditField
        ChangeFunctionPathButton        matlab.ui.control.Button
        readDataPath                    matlab.ui.control.EditField
        SelectDataDirectoryButton       matlab.ui.control.Button
        RetinalCapillaryPerfusionAnalysisLabel  matlab.ui.control.Label
        RegisterationRunButton          matlab.ui.control.Button
    end


    properties (Access = public)
        outputArrayMsg % Description
        loadloc
        disease
        subject
        datasize

        proj_layer
        timepoints
        tp % Timepoint currently processing
        save_folder
        save_comp
        numBscans
        numAscans
        funcpath

        savefns

        loadloc_enface
        loadloc_cube

        save_registered
        save_segmentation
        save_volume
        save_averaged
        save_mat
        save_cov
        save_mat_proj
        save_cov_proj
        % save_OCT_registered_V

        DNN_model
        ROI_Ceiling  double  % Ceiling of ROI for CoV computation
        ROI_Floor    double  % Floor of ROI for CoV computation
        cropped_amount
        depthROI_CoV
        depthROI_reg
        computed_OCTA_averaged

        % for repeatability eval
        save_comp_reg

        CancelFlag
    end

    methods (Access = public)

        function outPut(app, str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.outputArrayMsg=horzcat(string,app.outputArrayMsg); % 水平串联数组
            app.RuntimeLogTextArea.Value = app.outputArrayMsg; % 给TextArea赋值
        end

        function cleanOutPut(app, str)
            if nargin < 2
                str = [];
            end
            
            app.outputArrayMsg = {};
            app.outputArrayMsg{1}= sprintf('%s\n', str);
            app.RuntimeLogTextArea.Value = app.outputArrayMsg{1};
        end

        % Function to update drop-down
        function updateDropDown(app)
            % Example: Fetch new options (replace with actual data fetching)
            newOptions = app.timepoints;%{'Option A', 'Option B', 'Option C', datestr(now, 'HH:MM:SS')};
            app.SelectTimepointsLabel.Visible = "on";
            app.SelectTimepointsDropDown.Visible = "on";

            % Update the drop-down items on the main thread
            % Use drawnow to ensure thread safety
            if isvalid(app.SelectTimepointsDropDown)
                app.SelectTimepointsDropDown.Items = newOptions;

                % Optionally, set the selected value
                app.SelectTimepointsDropDown.Value = newOptions{1};

                % Update the label
                % app.LabelSelected.Text = ['Selected: ' app.DropDownOptions.Value];
            end
        end

        % Cleanup function: Code that runs when the app is deleted
        % function Delete(app)
        %     if isvalid(app.UpdateTimer)
        %         stop(app.UpdateTimer);
        %         delete(app.UpdateTimer);
        %     end
        % end

        function showROIInputDialog(app)
            % Create a modal pop-up dialog window
            dlgWidth = 600;
            dlgHeight = 250;
            dlg = uifigure('Position', [app.UIFigure.Position(1)+100, ...
                app.UIFigure.Position(2)+100, ...
                dlgWidth, dlgHeight], ...
                'Name', 'ROI Input Dialog', ...
                'Resize', 'off');

            % Instructional Text
            instructions = sprintf(['** Refer to the pre-selected OCT volume template under folder ''~/Restults3D/Average/*_cube_z_all.tif'' for boundary determination\n' ...
                '** Make sure to leave some space above the ILM for noise estimation\n' ...
                '** Make sure all retinal structure (include the choroid) is included']);
            instructionLabel = uilabel(dlg, ...
                'Text', instructions, ...
                'Position', [20 dlgHeight-100 dlgWidth-40 80], ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'WordWrap', true);

            % Ceiling Input
            ceilingLabel = uilabel(dlg, ...
                'Text', 'Enter the ceiling of ROI for CoV computation (in pixels):', ...
                'Position', [20 dlgHeight-150 400 22], ...
                'HorizontalAlignment', 'left');

            ceilingEdit = uieditfield(dlg, 'numeric', ...
                'Position', [450 dlgHeight-155 120 25], ...
                'Limits', [0 Inf], ...
                'Value', 100); % Default value

            % Floor Input
            floorLabel = uilabel(dlg, ...
                'Text', 'Enter the floor of ROI for CoV computation (in pixels):', ...
                'Position', [20 dlgHeight-180 400 22], ...
                'HorizontalAlignment', 'left');

            floorEdit = uieditfield(dlg, 'numeric', ...
                'Position', [450 dlgHeight-185 120 25], ...
                'Limits', [0 Inf], ...
                'Value', 800); % Default value

            % Enter Button
            enterButton = uibutton(dlg, 'push', ...
                'Text', 'Enter', ...
                'Position', [150 20 100 30], ...
                'ButtonPushedFcn', @(btn,event) enterCallback(app, btn, floorEdit, ceilingEdit, dlg));

            % Cancel Button
            cancelButton = uibutton(dlg, 'push', ...
                'Text', 'Cancel', ...
                'Position', [350 20 100 30], ...
                'ButtonPushedFcn', @(btn,event) cancelCallback(app,btn,dlg));

            % Center the dialog on the screen
            % dlg.Center = 'on';

            % Wait for the user to respond
            uiwait(dlg);
        end

        % Callback for the Enter button
        function enterCallback(app, ~, floorEdit, ceilingEdit, dlg)
            % Retrieve user inputs
            floorValue = floorEdit.Value;
            ceilingValue = ceilingEdit.Value;

            % Input Validation
            if isempty(floorValue) || isempty(ceilingValue)
                uialert(dlg, 'Both Ceiling and Floor values must be provided.', 'Input Error');
                return;
            end
            if floorValue <= ceilingValue
                uialert(dlg, 'Ceiling value must be less than floor value.', 'Input Error');
                return;
            end

            % Assign values to app properties
            app.ROI_Floor = floorValue;
            app.ROI_Ceiling = ceilingValue;


            % Optionally, display confirmation or proceed with further actions
            uialert(dlg, sprintf('ROI Ceiling set to %d pixels.\nROI Floor set to %d pixels.', ...
                app.ROI_Ceiling, app.ROI_Floor), 'Input Received');

            % Resume execution and close the dialog
            uiresume(dlg);
            close(dlg);
        end

        % Callback for the Cancel button
        function cancelCallback(app, ~, dlg)
            % Resume execution without saving inputs
            uiresume(dlg);
            close(dlg);
        end

        function computeCoV(app)
            app.CancelFlag = false;
            outPut(app,['Starting computing the CoV at timepoint: ', app.tp])
            % if exist(fullfile(app.save_mat,'depthROI_CoV.mat'),'file')
            % 
            %     app.depthROI_CoV = importdata(fullfile(app.save_mat,'depthROI_CoV.mat'));
            % 
            %     if isempty(app.depthROI_CoV)
            %         showROIInputDialog(app);
            %         depthROI = [app.ROI_Ceiling, app.ROI_Floor];
            %         save(fullfile(app.save_mat,'depthROI_CoV.mat'),'depthROI','-v7.3');clearvars depthROI
            %         app.depthROI_CoV = [app.ROI_Ceiling, app.ROI_Floor];
            %     end
            % 
            % else
                showROIInputDialog(app);
                depthROI = [app.ROI_Ceiling, app.ROI_Floor];
                save(fullfile(app.save_mat,'depthROI_CoV.mat'),'depthROI','-v7.3');clearvars depthROI
                app.depthROI_CoV = [app.ROI_Ceiling, app.ROI_Floor];
            % end
            outPut(app,['Depth ROI for CoV computation = ', num2str(app.depthROI_CoV)])
            app.UpperCovCompute.Value = app.depthROI_CoV(1);
            app.LowerCovCompute.Value = app.depthROI_CoV(2);

            outPut(app, 'Deriving vessel segmentation mask...');
            save_retina_fn_Z = fullfile(app.save_mat,strcat('registered_',app.tp,'_enfaceOCTA_fromZeiss.mat'));
            outPut(app, ['Load machine-processed en face OCTA images at: ', save_retina_fn_Z]);

            retina_reg_Z = importdata(save_retina_fn_Z);
            retina_reg_Z = retina_reg_Z(app.cropped_amount+1:end-app.cropped_amount,app.cropped_amount+1:end-app.cropped_amount,:);

            % retina_reg_V = importdata(save_retina_fn_V);
            if app.CancelFlag
                outPut(app, 'Operation cancelled by user.');
                return
            end

            % create dnn mask
            retina_reg_Z_mask = retina_reg_Z;%(cropped_amount+1:end-cropped_amount,cropped_amount+1:end-cropped_amount,:);
            retina_mean_Z_mask = mean(retina_reg_Z_mask, 3, "omitnan");
            retina_mean_Z_mask(isnan(retina_mean_Z_mask)) = 0;

            figure; imshow(retina_reg_Z_mask(:,:,1),[])
            retina_mean_Z_mask = imresize(retina_mean_Z_mask, [1024 1024]);
            avg_dnn_all = DNN_Vessel_Segmentation_Updated(retina_mean_Z_mask, app.DNN_model);

            if app.CancelFlag
                outPut(app, 'Operation cancelled by user.');
                return
            end

            tic
            avg_mask_all = imresize(avg_dnn_all,[app.numAscans-2*app.cropped_amount,app.numBscans-2*app.cropped_amount]);
            avg_mask_all(avg_mask_all < 0.5) = 0; avg_mask_all(avg_mask_all >=0.5) = 1;
            imwrite(double(avg_mask_all), fullfile(app.save_cov,strcat(app.tp,'_avg_mask_all','.tif')));
            toc
            figure;imshow(avg_mask_all,[])


            save_mask_fn = fullfile(app.save_mat,'avg_mask_all.mat');
            save(save_mask_fn,'avg_mask_all','-v7.3');
            outPut(app, ['Vessel segmentation result is saved as: ', save_mask_fn]);

            % compute CoV
            % savefn_mat_V = fullfile(app.save_mat_proj,strcat(app.tp,'_OCTA_fromVolume_CoV','.mat'))
            % savefn_img = fullfile(app.save_cov_proj,strcat(app.tp,'_OCTA_fromVolume_CoV','.png'));
            % savefn_mask = fullfile(app.save_cov_proj,strcat('masked_',app.tp,'_enfaceOCTA_fromVolume.tif'));
            % compute_CoV_2D(retina_reg_V,0,avg_mask_all, savefn_mat_V, savefn_img, "no", savefn_mask);
            %
            % savefn_mat_Z = fullfile(app.save_mat_proj,strcat(app.tp,'_OCTA_fromZeiss_CoV','.mat'))
            % savefn_img = fullfile(app.save_cov_proj,strcat(app.tp,'_OCTA_fromZeiss_CoV','.png'));
            % savefn_mask = fullfile(app.save_cov_proj,strcat('masked_',app.tp,'_enfaceOCTA_fromZeiss.tif'));
            % compute_CoV_2D(retina_reg_Z,0,avg_mask_all, savefn_mat_Z, savefn_img, "no",savefn_mask);

            % convert binary mask for manual correction
            convert_to_nifti(app)

            ftemp = 156;
            compute_vol_CoV(app, app.depthROI_CoV, ftemp);
            close all

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.outputArrayMsg{1}= sprintf('%s\n', '***Start of OCT Processing***');
            app.RuntimeLogTextArea.Value = app.outputArrayMsg{1};
            app.cropped_amount = app.cropSize.Value;
            app.loadloc="";
            % app.loadloc_seg = "";
            app.funcpath = pwd;
            addpath(app.funcpath);
            app.timepoints = {};
            app.funcpathDirect.Value = app.funcpath;
            app.CancelFlag = false;
            % app.loadSeg.Value="Not yet selected";
        end

        % Button pushed function: ClearRuntimeLog
        function ClearRuntimeLogPushed(app, event)
            cleanOutPut(app);
            % app.outputArrayMsg{1}= sprintf('%s\n', '***Start of OCT Registration***');
            % app.outputArrayMsg = {};
            % app.RuntimeLogTextArea.Value = app.outputArrayMsg{1};
        end

        % Button pushed function: CancelProcessing
        function CancelProcessingButtonPushed(app, event)
            app.CancelFlag = true; % Set the cancel flag to true
        end

        % Button pushed function: ChangeFunctionPathButton
        function ChangeFunctionPathButtonPushed(app, event)
            try
                app.funcpath = uigetdir(pwd);
                app.funcpathDirect.Value = app.funcpath;
                outPut(app,['Helper function folder has been changed to: ', app.funcpath]);
                addpath(app.funcpath);
                cd(app.funcpath)
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: SelectDataDirectoryButton
        function SelectDataDirectoryButtonPushed(app, event)
            try
                app.loadloc = uigetdir(pwd);
                app.readDataPath.Value=app.loadloc;
                outPut(app,strcat('Root:',app.loadloc));
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: setParameters
        function setParametersPushed(app, event)
            try
                app.loadloc = app.readDataPath.Value;
                app.disease = app.DiseaseDropDown.Value;
                app.subject = app.SubjectIDEditField.Value;
                app.timepoints = split(app.readtimepoints.Value, ',');%{app.readtimepoints.Value};
                outPut(app,app.readtimepoints.Value)
                app.datasize = [app.AscanEditField.Value, app.PointEditField.Value, app.FrameEditField.Value];
                app.numBscans = app.FrameEditField.Value;
                app.numAscans = app.AscanEditField.Value;
                app.proj_layer = "NFL_ONL";
                app.DNN_model = app.funcpathDirect.Value;
                cd(app.funcpath)
                app.CancelFlag = false;

                app.save_folder = fullfile(app.loadloc, app.disease, app.subject, 'Results3D');
                if ~exist(app.save_folder)
                    mkdir(app.save_folder)
                end

                app.save_comp = fullfile(app.save_folder,'TemporalAnlysis',app.proj_layer);
                if ~exist(app.save_comp)
                    mkdir(app.save_comp)
                end

                app.save_comp_reg = fullfile(app.save_comp, 'Registered');
                if ~exist(app.save_comp_reg,'dir')
                    mkdir(app.save_comp_reg);
                end

                for m = 1:length(app.timepoints)
                    % display the timepoint message
                    if ~exist(fullfile(fullfile(app.loadloc, app.disease, app.subject,app.timepoints{m})),'dir')
                        uialert(app.UIFigure, 'No data can be found with the parameter provided.',  "Error", "Icon","error" );
                        break
                    end

                    outPut(app, ["Data to be processed: ", fullfile(app.loadloc, app.disease, app.subject, app.timepoints{m})]);
                end
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: RegisterationRunButton
        function RegisterationRunButtonPushed(app, event)
            try
                app.CancelFlag = false;
                % currentContent = app.RuntimeLogTextArea.Value;
                % app.RuntimeLogTextArea.Value = [{'>>'};{'Starting to registeration...'};{' '}; currentContent];


                for m = 1:length(app.timepoints)
                    app.tp = app.timepoints{m};
                    % define saving folders
                    app.savefns = fullfile(app.save_folder, app.tp);%,'Extraction');
                    if ~exist(app.savefns,'dir')
                        mkdir(app.savefns)
                        outPut(app, ['Results will be saved at: ', app.savefns]);
                    end

                    app.loadloc_enface = fullfile(app.loadloc, app.disease, app.subject, app.tp);
                    app.loadloc_cube = fullfile(app.loadloc, app.disease, app.subject, strcat(app.subject,"_CUBE"), app.tp);

                    app.save_registered = fullfile(app.loadloc_cube,'CropRegistered');
                    if ~exist(app.save_registered,'dir')
                        mkdir(app.save_registered)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_registered]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_segmentation =fullfile(app.loadloc_cube,'CropSegmentation');
                    if ~exist(app.save_segmentation,'dir')
                        mkdir(app.save_segmentation)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_segmentation]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_volume = fullfile(app.savefns,'Cropped_V');
                    if ~exist(app.save_volume,'dir')
                        mkdir(app.save_volume)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_volume]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_averaged = fullfile(app.savefns,'Average');
                    if ~exist(app.save_averaged,'dir')
                        mkdir(app.save_averaged)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_averaged]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_mat = fullfile(app.savefns,'mat');
                    if ~exist(app.save_mat,'dir')
                        mkdir(app.save_mat)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_mat]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_cov = fullfile(app.savefns,'CoV');
                    if ~exist(app.save_cov,'dir')
                        mkdir(app.save_cov)
                        % app.RuntimeLogTextArea.Value = [{['Created folder: ', save_cov]}; app.RuntimeLogTextArea.Value];
                    end

                    app.save_mat_proj = fullfile(app.save_mat,app.proj_layer);
                    if ~exist(app.save_mat_proj,'dir')
                        mkdir(app.save_mat_proj)
                        outPut(app, ['Created folder: ', app.save_mat_proj]);
                    end

                    app.save_cov_proj = fullfile(app.save_cov,app.proj_layer);
                    if ~exist(app.save_cov_proj,'dir')
                        mkdir(app.save_cov_proj)
                        outPut(app, ['Created folder: ', app.save_cov_proj]);
                    end

                    % app.save_OCT_registered_V = fullfile(save_reg_proj,'Registered_V');
                    % if ~exist(app.save_OCT_registered_V,'dir')
                    %     mkdir(app.save_OCT_registered_V)
                    % end

                    app.loadloc_enface = fullfile(app.loadloc, app.disease, app.subject, app.tp);
                    outPut(app, ["en face path: ", app.loadloc_enface]);
                    app.loadloc_cube = fullfile(app.loadloc, app.disease, app.subject, strcat(app.subject,"_CUBE"), app.tp);
                    outPut(app, ["volume path: ", app.loadloc_cube]);

                    % app.savefns = fullfile(app.save_folder, app.timepoints{m});%,'Extraction');
                    % if ~exist(savefns,'dir')
                    %     mkdir(savefns)
                    % end

                    app.save_mat = fullfile(app.savefns,'mat');
                    if ~exist(app.save_mat,'dir')
                        mkdir(app.save_mat)
                    end

                    if exist(fullfile(app.save_mat,'depthROI_Register.mat'),'file')
                        reg_quest = questdlg("Do you want to change the registration range and redo this process?",...
                            'Re-registration',...
                            'Yes','No', ...
                            'No');
                        switch reg_quest
                            case 'Yes'
                                upper = app.upperRegistration.Value;
                                lower = app.lowerRegistration.Value;
                                depthROI = [upper, lower];
                                save(fullfile(app.save_mat,'depthROI_Register.mat'),'depthROI','-v7.3');
                                app.depthROI_reg = depthROI; clear depthROI
                                outPut(app, ['depthROI = ',num2str(app.depthROI_reg)]);

                                [ref_retina_fns, ref_retina_loc] = uigetfile(fullfile(app.loadloc_enface,'*.bmp'),...
                                    'Choose template for OCTA projection images (machine-processed)');
                                app.template_proj_fn.Value = ref_retina_fns;

                                [ref_OCT_fns, ref_OCT_loc] = uigetfile(fullfile(app.loadloc_cube,'*_cube_z*.img'),...
                                    'Choose template for OCT volume data (file ends with _cube_z)');
                                app.template_cube_fn.Value = ref_OCT_fns;

                                ref_retina = double(imread(fullfile(ref_retina_loc,ref_retina_fns)));
                                ref_OCT_temp = double(read_OCT_vol(app,fullfile(ref_OCT_loc,ref_OCT_fns)));
                                ref_OCT = ref_OCT_temp(app.depthROI_reg(1):app.depthROI_reg(2),:,:);

                                %%% resize the reference en face OCTA
                                resize_ref_retina = flipud(permute(imresize(ref_retina,[app.datasize(1) app.datasize(3)]),[2,1]));
                                ref_retina = resize_ref_retina; clear resize_ref_retina;

                                %%% registration
                                multi_vol_registration_roi(app,ref_retina, ref_OCT, app.depthROI_reg);
                                if app.CancelFlag
                                    outPut(app, 'Operation cancelled by user.');
                                    return
                                end

                                %%% Compute CoV
                                computeCoV(app);
                                if app.CancelFlag
                                    outPut(app, 'Operation cancelled by user.');
                                    return
                                end

                            case 'No'
                                if exist(fullfile(app.save_mat,'CoV_fromBscans.mat'),'file')
                                    cov_quest = questdlg("Do you want to change the ROI and recalculate the volumatric CoV?",...
                                        'Re-compute CoV',...
                                        'Yes','No', ...
                                        'No');
                                    switch cov_quest
                                        case 'Yes'
                                            delete(fullfile(app.save_mat,'depthROI_CoV.mat'));

                                            computeCoV(app);
                                            if app.CancelFlag
                                                outPut(app, 'Operation cancelled by user.');
                                                return
                                            end
                                        case 'No'
                                            if ~exist(fullfile(app.savefns,'nifti','avg_mask_all.nii'),'file')
                                                convert_to_nifti(app)
                                            end
                                            continue
                                    end
                                else
                                    %%% Compute CoV
                                    computeCoV(app);
                                    if app.CancelFlag
                                        outPut(app, 'Operation cancelled by user.');
                                        return
                                    end
                                end
                        end
                    else
                        upper = app.upperRegistration.Value;
                        lower = app.lowerRegistration.Value;
                        depthROI = [upper, lower];
                        save(fullfile(app.save_mat,'depthROI_Register.mat'),'depthROI','-v7.3');
                        app.depthROI_reg = depthROI; clear depthROI

                        outPut(app, ['depthROI = ',num2str(app.depthROI_reg)]);

                        [ref_retina_fns, ref_retina_loc] = uigetfile(fullfile(app.loadloc_enface,'*.bmp'),...
                            'Choose template for OCTA projection images (machine-processed)');
                        app.template_proj_fn.Value = ref_retina_fns;

                        [ref_OCT_fns, ref_OCT_loc] = uigetfile(fullfile(app.loadloc_cube,'*_cube_z*.img'),...
                            'Choose template for OCT volume data (file ends with _cube_z)');
                        app.template_cube_fn.Value = ref_OCT_fns;

                        ref_retina = double(imread(fullfile(ref_retina_loc,ref_retina_fns)));
                        ref_OCT_temp = double(read_OCT_vol(app,fullfile(ref_OCT_loc,ref_OCT_fns)));
                        ref_OCT = ref_OCT_temp(app.depthROI_reg(1):app.depthROI_reg(2),:,:);

                        %%% resize the reference en face OCTA
                        resize_ref_retina = flipud(permute(imresize(ref_retina,[app.datasize(1) app.datasize(3)]),[2,1]));
                        ref_retina = resize_ref_retina; clear resize_ref_retina;

                        %%% registration
                        multi_vol_registration_roi(app,ref_retina,ref_OCT,app.depthROI_reg);
                        if app.CancelFlag
                            outPut(app, 'Operation cancelled by user.');
                            return
                        end
                        %%% Compute CoV
                        computeCoV(app);
                    end                    
                end
                outPut(app, 'Part I process completed.')

            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: GenerateProjectionImagesButton
        function GenerateProjectionImagesButtonPushed(app, event)
            try
                app.CancelFlag = false;
                imageProjection(app);
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: RepeatabilityReproducibilityButton
        function RepeatabilityReproducibilityButtonPushed(app, event)
            try
                app.CancelFlag = false;
                app.save_comp_reg = fullfile(app.save_folder,'TemporalAnlysis',app.proj_layer, 'Registered');
                outPut(app,["Results will be saved at: ", app.save_comp_reg])

                tp_template = app.timepoints{1};
                outPut(app,["Timepoint used as template: ", tp_template])

                register_across_tps(app,tp_template);
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Button pushed function: plotVisualization
        function plotVisualizationPushed(app, event)
            try
                app.CancelFlag = false;
                % cov_visualization(app);
                repeatability_eval(app);
                updateDropDown(app);
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end

        % Value changed function: SelectTimepointsDropDown
        function SelectTimepointsDropDownValueChanged(app, event)
            try
                % updateDropDown(app);
                app.tp = app.SelectTimepointsDropDown.Value;
                outPut(app, ['Plot results at: ', app.tp]);

                meas = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_measurement.mat')));
                th = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_userinput_threshold.mat')));


                if ishghandle(app.ZeissE) ~= 1
                    % if isvalid(app.ZeissE)
                    %     % ishghandle(app.ZeissE) == 1
                    %     cla(app.ZeissE);
                    %     cla(app.CustomE);
                    %     cla(app.CustomV);
                    % end
                    % plot ZeissE
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_normCoV_fromZeiss_threshold_',strrep(num2str(th.threshold_Z),'.','-'),'.png'));
                    imshow(imread(savefn_img),'Parent',app.ZeissE);
                    app.mu_ZeissE.Value = meas.mu_zeissE;
                    app.std_ZeissE.Value = meas.std_zeissE;
    
                    % plot CustomE
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_fromVolume_threshold_',strrep(num2str(th.threshold_V),'.','-'),'.png'));
                    imshow(imread(savefn_img),'Parent',app.CustomE);
                    app.mu_CustomE.Value = meas.mu_customE;
                    app.std_CustomE.Value = meas.std_customE;
    
                    % plot CustomV
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_proj_fromVolume_median_threshold_',strrep(num2str(th.threshold_Median),'.','-'),'.png'));
                    imshow(imread(savefn_img),'Parent',app.CustomV);
                    app.CustomV.Value = meas.mu_customV;
                    app.CustomV.Value = meas.mu_customV;
                else
                    app.tp = app.SelectTimepointsDropDown.Value;
                    outPut(app, ['Plot results at: ', app.tp]);
    
                    meas = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_measurement.mat')));
                    th = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_userinput_threshold.mat')));
    
                    % plot ZeissE
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_normCoV_fromZeiss_threshold_',strrep(num2str(th.threshold_Z),'.','-'),'.png'));
                    % imshow(imread(savefn_img),'Parent',app.ZeissE);
                    app.ZeissE.ImageSource = savefn_img;
                    app.zeissETitle.Visible = "on";
                    app.ZeissE.Visible = "on";
                    app.mu_ZeissE.Value = meas.mu_zeissE;
                    app.std_ZeissE.Value = meas.std_zeissE;
    
                    % plot CustomE
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_fromVolume_threshold_',strrep(num2str(th.threshold_V),'.','-'),'.png'));
                    % imshow(imread(savefn_img),'Parent',app.CustomE);
                    app.CustomE.ImageSource = savefn_img;
                    app.customETitle.Visible = "on";
                    app.CustomE.Visible = "on";
                    app.mu_CustomE.Value = meas.mu_customE;
                    app.std_CustomE.Value = meas.std_customE;
    
                    % plot CustomV
                    savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_proj_fromVolume_median_threshold_',strrep(num2str(th.threshold_Median),'.','-'),'.png'));
                    % imshow(imread(savefn_img),'Parent',app.CustomV);
                    app.CustomV.ImageSource = savefn_img;
                    app.customVTitle.Visible = "on";
                    app.CustomV.Visible = "on";
                    app.mu_CustomV.Value = meas.mu_customV;
                    app.std_CustomV.Value = meas.std_customV;
                    
                end
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end

        end

        % Button pushed function: VisualizationButton
        function VisualizationButtonPushed(app, event)
            try
                updateDropDown(app);

                app.mu_ZeissE.Visible = "on";
                app.std_ZeissE.Visible = "on";
                app.mu_ZeissELabel.Visible = "on";
                app.std_ZeissELabel.Visible = "on";

                app.mu_CustomE.Visible = "on";
                app.std_CustomE.Visible = "on";
                app.mu_CustomELabel.Visible = "on";
                app.std_CustomELabel.Visible = "on";

                app.mu_CustomV.Visible = "on";
                app.std_CustomV.Visible = "on";
                app.mu_CustomVLabel.Visible = "on";
                app.std_CustomVLabel.Visible = "on";

                
                app.tp = app.SelectTimepointsDropDown.Value;
                outPut(app, ['Plot results at: ', app.tp]);

                meas = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_measurement.mat')));
                th = importdata(fullfile(app.save_comp_reg, strcat(app.tp,'_userinput_threshold.mat')));

                % plot ZeissE
                savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_normCoV_fromZeiss_threshold_',strrep(num2str(th.threshold_Z),'.','-'),'.png'));
                % imshow(imread(savefn_img),'Parent',app.ZeissE);
                app.ZeissE.ImageSource = savefn_img;
                app.zeissETitle.Visible = "on";
                app.ZeissE.Visible = "on";
                app.mu_ZeissE.Value = meas.mu_zeissE;
                app.std_ZeissE.Value = meas.std_zeissE;

                % plot CustomE
                savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_fromVolume_threshold_',strrep(num2str(th.threshold_V),'.','-'),'.png'));
                % imshow(imread(savefn_img),'Parent',app.CustomE);
                app.CustomE.ImageSource = savefn_img;
                app.customETitle.Visible = "on";
                app.CustomE.Visible = "on";
                app.mu_CustomE.Value = meas.mu_customE;
                app.std_CustomE.Value = meas.std_customE;

                % plot CustomV
                savefn_img = fullfile(app.save_comp_reg,strcat(app.tp,'_CoV_proj_fromVolume_median_threshold_',strrep(num2str(th.threshold_Median),'.','-'),'.png'));
                % imshow(imread(savefn_img),'Parent',app.CustomV);
                app.CustomV.ImageSource = savefn_img;
                app.customVTitle.Visible = "on";
                app.CustomV.Visible = "on";
                app.mu_CustomV.Value = meas.mu_customV;
                app.std_CustomV.Value = meas.std_customV;
            catch e
                errorMessage = ['Error processing data: ', e.message];
                outPut(app, errorMessage);
                uialert(app.UIFigure, ['Error processing data: ' e.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegisterationRunButton = uibutton(app.GridLayout, 'push');
            app.RegisterationRunButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterationRunButtonPushed, true);
            app.RegisterationRunButton.Text = 'Multi-volume Registration';
            app.RetinalCapillaryPerfusionAnalysisLabel = uilabel(app.GridLayout);
            app.RetinalCapillaryPerfusionAnalysisLabel.Text = 'Retinal Capillary Perfusion Analysis';
            app.SelectDataDirectoryButton = uibutton(app.GridLayout, 'push');
            app.SelectDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataDirectoryButtonPushed, true);
            app.SelectDataDirectoryButton.Text = 'Select Data Directory';
            app.readDataPath = uieditfield(app.GridLayout, 'text');
            app.ChangeFunctionPathButton = uibutton(app.GridLayout, 'push');
            app.ChangeFunctionPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFunctionPathButtonPushed, true);
            app.ChangeFunctionPathButton.Text = 'Change Function Path';
            app.funcpathDirect = uieditfield(app.GridLayout, 'text');
            app.setParameters = uibutton(app.GridLayout, 'push');
            app.setParameters.ButtonPushedFcn = createCallbackFcn(app, @setParametersPushed, true);
            app.setParameters.Text = 'Set Parameters';
            app.DMultivolumeRegistrationLabel = uilabel(app.GridLayout);
            app.DMultivolumeRegistrationLabel.Text = '3D Multi-volume Registration';
            app.DefineInitialParametersLabel = uilabel(app.GridLayout);
            app.DefineInitialParametersLabel.Text = 'Define Initial Parameters';
            app.ComputeCoVLabel = uilabel(app.GridLayout);
            app.ComputeCoVLabel.Text = 'Compute CoV';
            app.EntertheupperboundaryofthedepthforregistrationLabel = uilabel(app.GridLayout);
            app.EntertheupperboundaryofthedepthforregistrationLabel.Text = 'Enter the lower boundary of the depth for registration:';
            app.lowerRegistration = uieditfield(app.GridLayout, 'numeric');
            app.lowerRegistration.Limits = [0 Inf];
            app.lowerRegistration.Value = 1400;
            app.EntertheupperboundaryofthedepthforCoVcomputationLabel = uilabel(app.GridLayout);
            app.EntertheupperboundaryofthedepthforCoVcomputationLabel.Text = 'Upper boundary of the depth for CoV computation:';
            app.UpperCovCompute = uieditfield(app.GridLayout, 'numeric');
            app.UpperCovCompute.Limits = [0 Inf];
            app.UpperCovCompute.Editable = 'off';
            app.UpperCovCompute.Value = 200;
            app.EntertheupperboundaryofthedepthforCoVcomputationLabel_2 = uilabel(app.GridLayout);
            app.EntertheupperboundaryofthedepthforCoVcomputationLabel_2.Text = 'Lower boundary of the depth for CoV computation:';
            app.LowerCovCompute = uieditfield(app.GridLayout, 'numeric');
            app.LowerCovCompute.Limits = [0 Inf];
            app.LowerCovCompute.Editable = 'off';
            app.LowerCovCompute.Value = 800;
            app.DiseaseDropDownLabel = uilabel(app.GridLayout);
            app.DiseaseDropDownLabel.Text = 'Disease';
            app.DiseaseDropDown = uidropdown(app.GridLayout);
            app.DiseaseDropDown.Items = {'CONTROL', 'NTG', 'OAG'};
            app.DiseaseDropDown.Value = 'CONTROL';
            app.SubjectIDEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.GridLayout, 'text');
            app.SubjectIDEditField.Value = 'CV092';
            app.TimepointsEditFieldLabel = uilabel(app.GridLayout);
            app.TimepointsEditFieldLabel.Text = 'Timepoint(s)';
            app.readtimepoints = uieditfield(app.GridLayout, 'text');
            app.readtimepoints.Value = 'OS_MAC_12131420PM';
            app.PointEditFieldLabel = uilabel(app.GridLayout);
            app.PointEditFieldLabel.Text = 'Point #';
            app.PointEditField = uieditfield(app.GridLayout, 'numeric');
            app.PointEditField.Limits = [0 Inf];
            app.PointEditField.AllowEmpty = 'on';
            app.PointEditField.Value = 1536;
            app.FrameEditFieldLabel = uilabel(app.GridLayout);
            app.FrameEditFieldLabel.Text = 'Frame #';
            app.FrameEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameEditField.Limits = [0 Inf];
            app.FrameEditField.AllowEmpty = 'on';
            app.FrameEditField.Value = 300;
            app.AscanEditFieldLabel = uilabel(app.GridLayout);
            app.AscanEditFieldLabel.Text = 'Ascan #';
            app.AscanEditField = uieditfield(app.GridLayout, 'numeric');
            app.AscanEditField.Limits = [0 Inf];
            app.AscanEditField.AllowEmpty = 'on';
            app.AscanEditField.Value = 300;
            app.EnterthelowerboundaryofthedepthforregistrationLabel = uilabel(app.GridLayout);
            app.EnterthelowerboundaryofthedepthforregistrationLabel.Text = 'Enter the upper boundary of the depth for registration:';
            app.upperRegistration = uieditfield(app.GridLayout, 'numeric');
            app.upperRegistration.Limits = [0 Inf];
            app.upperRegistration.Value = 50;
            app.RuntimeLogTextAreaLabel = uilabel(app.GridLayout);
            app.RuntimeLogTextAreaLabel.Text = 'Runtime Log';
            app.RuntimeLogTextArea = uitextarea(app.GridLayout);
            app.template_proj_fn = uieditfield(app.GridLayout, 'text');
            app.template_proj_fn.Editable = 'off';
            app.template_proj_fn.Value = 'Template of projection image (machine-processed)';
            app.template_cube_fn = uieditfield(app.GridLayout, 'text');
            app.template_cube_fn.Editable = 'off';
            app.template_cube_fn.Value = 'Template of OCT volume data (raw data)';
            app.IftherearemultipletimepointsseparatethemusingcommaLabel = uilabel(app.GridLayout);
            app.IftherearemultipletimepointsseparatethemusingcommaLabel.Text = '** If there are multiple timepoints, separate them using comma (,)';
            app.SelectedRegistrationTemplateLabel = uilabel(app.GridLayout);
            app.SelectedRegistrationTemplateLabel.Text = 'Selected Registration Template';
            app.OCTAprojectionimageLabel = uilabel(app.GridLayout);
            app.OCTAprojectionimageLabel.Text = 'OCTA projection image';
            app.RawOCTvolumeLabel = uilabel(app.GridLayout);
            app.RawOCTvolumeLabel.Text = 'Raw OCT volume';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '** OCTA projection image and OCT volume should be from the same data (check the timestamp in the filenames)';
            app.ClearRuntimeLog = uibutton(app.GridLayout, 'push');
            app.ClearRuntimeLog.ButtonPushedFcn = createCallbackFcn(app, @ClearRuntimeLogPushed, true);
            app.ClearRuntimeLog.Text = 'Clear';
            app.CroppingSizeinpixelEditFieldLabel = uilabel(app.GridLayout);
            app.CroppingSizeinpixelEditFieldLabel.Text = 'Cropping  Size (in pixel)';
            app.cropSize = uieditfield(app.GridLayout, 'numeric');
            app.cropSize.Value = 20;
            app.eachsideoftheprojectionimageLabel = uilabel(app.GridLayout);
            app.eachsideoftheprojectionimageLabel.Text = '** each side of the projection image';
            app.SegmentationLabel = uilabel(app.GridLayout);
            app.SegmentationLabel.Text = 'Segmentation';
            app.LayersegmentationFn = uieditfield(app.GridLayout, 'text');
            app.CancelProcessing = uibutton(app.GridLayout, 'push');
            app.CancelProcessing.ButtonPushedFcn = createCallbackFcn(app, @CancelProcessingButtonPushed, true);
            app.CancelProcessing.Text = 'Cancel';
            app.GenerateProjectionImagesButton = uibutton(app.GridLayout, 'push');
            app.GenerateProjectionImagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProjectionImagesButtonPushed, true);
            app.GenerateProjectionImagesButton.Text = 'Generate Projection Images';
            app.OffsetEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.showOffset = uieditfield(app.GridLayout, 'numeric');
            app.showOffset.Editable = 'off';
            app.fornoiseestimationdeterminedviatrialanderrorLabel = uilabel(app.GridLayout);
            app.fornoiseestimationdeterminedviatrialanderrorLabel.Text = '** for noise estimation (determined via trial and error)';
            app.plotVisualization = uibutton(app.GridLayout, 'push');
            app.plotVisualization.ButtonPushedFcn = createCallbackFcn(app, @plotVisualizationPushed, true);
            app.plotVisualization.Text = 'Threshold & Visualization';
            app.SelectTimepointsDropDown = uidropdown(app.GridLayout);
            app.SelectTimepointsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTimepointsDropDownValueChanged, true);
            app.SelectTimepointsDropDown.Visible = 'off';
            app.mu_ZeissE = uieditfield(app.GridLayout, 'numeric');
            app.mu_ZeissE.Visible = 'off';
            app.std_ZeissE = uieditfield(app.GridLayout, 'numeric');
            app.std_ZeissE.Visible = 'off';
            app.mu_CustomE = uieditfield(app.GridLayout, 'numeric');
            app.mu_CustomE.Visible = 'off';
            app.std_CustomE = uieditfield(app.GridLayout, 'numeric');
            app.std_CustomE.Visible = 'off';
            app.mu_CustomV = uieditfield(app.GridLayout, 'numeric');
            app.mu_CustomV.Visible = 'off';
            app.std_CustomV = uieditfield(app.GridLayout, 'numeric');
            app.std_CustomV.Visible = 'off';
            app.VisualizationButton = uibutton(app.GridLayout, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.VisualizationButton.Text = 'Visualization';
            app.mu_ZeissELabel = uilabel(app.GridLayout);
            app.mu_ZeissELabel.Visible = 'off';
            app.mu_ZeissELabel.Text = 'MEAN';
            app.std_ZeissELabel = uilabel(app.GridLayout);
            app.std_ZeissELabel.Visible = 'off';
            app.std_ZeissELabel.Text = 'STD';
            app.mu_CustomELabel = uilabel(app.GridLayout);
            app.mu_CustomELabel.Visible = 'off';
            app.mu_CustomELabel.Text = 'MEAN';
            app.std_CustomELabel = uilabel(app.GridLayout);
            app.std_CustomELabel.Visible = 'off';
            app.std_CustomELabel.Text = 'STD';
            app.mu_CustomVLabel = uilabel(app.GridLayout);
            app.mu_CustomVLabel.Visible = 'off';
            app.mu_CustomVLabel.Text = 'MEAN';
            app.std_CustomVLabel = uilabel(app.GridLayout);
            app.std_CustomVLabel.Visible = 'off';
            app.std_CustomVLabel.Text = 'STD';
            app.ZeissE = uiimage(app.GridLayout);
            app.ZeissE.Visible = 'off';
            app.CustomE = uiimage(app.GridLayout);
            app.CustomE.Visible = 'off';
            app.CustomV = uiimage(app.GridLayout);
            app.CustomV.Visible = 'off';
            app.zeissETitle = uilabel(app.GridLayout);
            app.zeissETitle.Visible = 'off';
            app.zeissETitle.Text = 'CoV using ZeissE';
            app.customETitle = uilabel(app.GridLayout);
            app.customETitle.Visible = 'off';
            app.customETitle.Text = 'CoV using CustomE';
            app.customVTitle = uilabel(app.GridLayout);
            app.customVTitle.Visible = 'off';
            app.customVTitle.Text = 'CoV using CustomV';
            app.SelectTimepointsLabel = uilabel(app.GridLayout);
            app.SelectTimepointsLabel.Visible = 'off';
            app.SelectTimepointsLabel.Text = 'Select Timepoints';
            app.RepeatabilityReproducibilityButton = uibutton(app.GridLayout, 'push');
            app.RepeatabilityReproducibilityButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatabilityReproducibilityButtonPushed, true);
            app.RepeatabilityReproducibilityButton.Text = 'Repeatability & Reproducibility';
            app.CroppingSizeinpixelEditFieldLabel_2 = uilabel(app.GridLayout);
            app.CroppingSizeinpixelEditFieldLabel_2.Text = 'Cropping  Size (in pixel)';
            app.showCropCoV = uieditfield(app.GridLayout, 'numeric');
            app.showCropCoV.Value = 15;
            app.forCoVmapeachsideoftheprojectionimageLabel = uilabel(app.GridLayout);
            app.forCoVmapeachsideoftheprojectionimageLabel.Text = '** for CoV map; each side of the projection image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COV_2o

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end